classdef InterfazNew < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ActividadFisica                matlab.ui.control.EditField
        ActividadFisicaEditFieldLabel  matlab.ui.control.Label
        Alcohol                        matlab.ui.control.EditField
        AlcoholEditFieldLabel          matlab.ui.control.Label
        Fuma                           matlab.ui.control.EditField
        FumaEditFieldLabel             matlab.ui.control.Label
        CalcularButton_2               matlab.ui.control.Button
        VariableDropDown               matlab.ui.control.DropDown
        VariableDropDownLabel          matlab.ui.control.Label
        MediaEditField                 matlab.ui.control.NumericEditField
        MediaEditFieldLabel            matlab.ui.control.Label
        DesviacionEstandarEditField    matlab.ui.control.NumericEditField
        DesviacionEstandarEditFieldLabel  matlab.ui.control.Label
        CalcularpMAButton              matlab.ui.control.Button
        IMCButton                      matlab.ui.control.Button
        DatosButton                    matlab.ui.control.Button
        GraficaButton                  matlab.ui.control.Button
        PMAEditField                   matlab.ui.control.NumericEditField
        PMAEditFieldLabel              matlab.ui.control.Label
        CLASF                          matlab.ui.control.EditField
        CASIMC                         matlab.ui.control.NumericEditField
        IMCEditFieldLabel              matlab.ui.control.Label
        Edad                           matlab.ui.control.NumericEditField
        EdadEditFieldLabel             matlab.ui.control.Label
        ID                             matlab.ui.control.NumericEditField
        IDdelusuarioEditFieldLabel     matlab.ui.control.Label
        GraficaDropDown                matlab.ui.control.DropDown
        GraficaDropDownLabel           matlab.ui.control.Label
        ANALISISBASEDEDATOSLabel       matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
    end


            properties (Access = private)
        HeartData
    end
    
    methods (Access = private)

             function Preprocesamiento(app)
            app.HeartData.height = app.HeartData.height / 100;
            
        end
        
        function edad(app)
            app.HeartData.age = app.HeartData.age/365;
            
        end

        
        function alcohol(app)
            app.HeartData.alco = categorical(app.HeartData.alco, [0 1], {'No Toma', 'Toma'});
        end

        function fumar(app)
            app.HeartData.smoke = categorical(app.HeartData.smoke, [0 1], {'No Fuma', 'Fuma'});
        end
        function Actividad(app)
            app.HeartData.active = categorical(app.HeartData.active, [0 1], {'No hace', 'Hace'});
    end  
   
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load('heart_data.mat', 'heart_data');
            app.HeartData = heart_data;
        end

        % Button pushed function: DatosButton
        function DatosButtonPushed(app, event)
            id = app.ID.Value;

            edad =  (app.HeartData.age(id));
            app.Edad.Text = (num2str(edad, "%.0f"));

            fumar = (app.HeartData.smoke(id));
            app.Fuma.Text = (string(fumar));
            alcohol = (app.HeartData.alco(id));
            app.Alcohol.Text = string(alcohol);
            actividadfisica = (app.HeartData.active(id));
            app.ActividadFisica.Text = string(actividadfisica);


        end

        % Value changed function: ID
        function IDValueChanged(app, event)
            id = app.ID.Value;
            
        end

        % Value changed function: GraficaDropDown
        function GraficaDropDownValueChanged(app, event)
            value = app.GraficaDropDown.Value;
            
        end

        % Value changed function: CASIMC
        function CASIMCValueChanged(app, event)
            value = app.CASIMC.Value;
            
        end

        % Button pushed function: IMCButton
        function IMCButtonPushed(app, event)
            id=app.ID.Value;
peso = app. HeartData.weight(id);
altura = app. HeartData.height(id);

imc = peso/altura^2;

if (imc<16)
app.CASIMC.Text=(num2str(imc,"%.2f")); app.CLASF.Text=("delgadez severa");

elseif (imc >= 16) && (imc <= 16.99) 
    app.CASIMC.Text=(num2str(imc, "%.2f")); 
    app.CLASF.Text=("delgadez moderada")

elseif (imc >= 17) && (imc <= 18.49) 
    app.CASIMC.Text=(num2str(imc, "%.2f")); 
    app.CLASF.Text=("delgadez leve");
elseif (imc >= 18.5) && (imc <= 24.9) 
    app.CASIMC.Text=(num2str(imc, "%.2f")); 
    app.CLASF.Text=("Normal");
elseif (imc >= 25) && (imc <= 29.99) 
    app.CASIMC.Text=(num2str(imc, "%.2f")); 
    app.CLASF.Text=("Preobeso");
elseif (imc >= 30) && (imc <= 34.99)
    app.CASIMC.Text=(num2str(imc,"%.2f")); 
    app.CLASF.Text=("Obesidad leve");
elseif (imc >= 35) && (imc <= 39.99) 
    app.CASIMC.Text=(num2str(imc, "%.2f")); 
    app.CLASF.Text=("Obesidad media");
elseif (imc >= 40)
app.CASIMC.Text=(num2str(imc, "%.2f")); 
app.CLASF.Text=("Obesidad morbida");
end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ANALISISBASEDEDATOSLabel = uilabel(app.UIFigure);
            app.ANALISISBASEDEDATOSLabel.Text = '= ANALISIS BASE DE DATOS =';
            app.GraficaDropDownLabel = uilabel(app.UIFigure);
            app.GraficaDropDownLabel.Text = 'Grafica';
            app.GraficaDropDown = uidropdown(app.UIFigure);
            app.GraficaDropDown.Items = {'CVD', 'imc vs colesterol', 'glucosa vs presion arteriar'};
            app.GraficaDropDown.ValueChangedFcn = createCallbackFcn(app, @GraficaDropDownValueChanged, true);
            app.GraficaDropDown.Value = 'CVD';
            app.IDdelusuarioEditFieldLabel = uilabel(app.UIFigure);
            app.IDdelusuarioEditFieldLabel.Text = 'ID del usuario';
            app.ID = uieditfield(app.UIFigure, 'numeric');
            app.ID.Limits = [1 70000];
            app.ID.ValueChangedFcn = createCallbackFcn(app, @IDValueChanged, true);
            app.ID.Value = 1;
            app.EdadEditFieldLabel = uilabel(app.UIFigure);
            app.EdadEditFieldLabel.Text = 'Edad';
            app.Edad = uieditfield(app.UIFigure, 'numeric');
            app.IMCEditFieldLabel = uilabel(app.UIFigure);
            app.IMCEditFieldLabel.Text = 'IMC';
            app.CASIMC = uieditfield(app.UIFigure, 'numeric');
            app.CASIMC.ValueChangedFcn = createCallbackFcn(app, @CASIMCValueChanged, true);
            app.CLASF = uieditfield(app.UIFigure, 'text');
            app.PMAEditFieldLabel = uilabel(app.UIFigure);
            app.PMAEditFieldLabel.Text = 'PMA';
            app.PMAEditField = uieditfield(app.UIFigure, 'numeric');
            app.GraficaButton = uibutton(app.UIFigure, 'push');
            app.GraficaButton.Text = 'Grafica';
            app.DatosButton = uibutton(app.UIFigure, 'push');
            app.DatosButton.ButtonPushedFcn = createCallbackFcn(app, @DatosButtonPushed, true);
            app.DatosButton.Text = 'Datos';
            app.IMCButton = uibutton(app.UIFigure, 'push');
            app.IMCButton.ButtonPushedFcn = createCallbackFcn(app, @IMCButtonPushed, true);
            app.IMCButton.Text = 'IMC';
            app.CalcularpMAButton = uibutton(app.UIFigure, 'push');
            app.CalcularpMAButton.Text = 'Calcular pMA';
            app.DesviacionEstandarEditFieldLabel = uilabel(app.UIFigure);
            app.DesviacionEstandarEditFieldLabel.Text = 'Desviacion Estandar';
            app.DesviacionEstandarEditField = uieditfield(app.UIFigure, 'numeric');
            app.MediaEditFieldLabel = uilabel(app.UIFigure);
            app.MediaEditFieldLabel.Text = 'Media';
            app.MediaEditField = uieditfield(app.UIFigure, 'numeric');
            app.VariableDropDownLabel = uilabel(app.UIFigure);
            app.VariableDropDownLabel.Text = 'Variable';
            app.VariableDropDown = uidropdown(app.UIFigure);
            app.VariableDropDown.Items = {'IMC', 'PAM', 'Colesterol'};
            app.VariableDropDown.Value = 'IMC';
            app.CalcularButton_2 = uibutton(app.UIFigure, 'push');
            app.CalcularButton_2.Text = 'Calcular';
            app.FumaEditFieldLabel = uilabel(app.UIFigure);
            app.FumaEditFieldLabel.Text = 'Fuma';
            app.Fuma = uieditfield(app.UIFigure, 'text');
            app.AlcoholEditFieldLabel = uilabel(app.UIFigure);
            app.AlcoholEditFieldLabel.Text = 'Alcohol';
            app.Alcohol = uieditfield(app.UIFigure, 'text');
            app.ActividadFisicaEditFieldLabel = uilabel(app.UIFigure);
            app.ActividadFisicaEditFieldLabel.Text = 'Actividad Fisica';
            app.ActividadFisica = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = InterfazNew

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end